classdef JMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure                % UI Figure
        ButtonGroup   matlab.ui.container.ButtonGroup % 功能
        ToggleButton  matlab.ui.control.ToggleButton  % 发送端
        ToggleButton2 matlab.ui.control.ToggleButton  % 接收端
        LabelTextArea matlab.ui.control.Label         % formula
        TextArea      matlab.ui.control.TextArea     
        Label         matlab.ui.control.Label         % result
        TextArea2     matlab.ui.control.TextArea     
        Button        matlab.ui.control.Button        % Send
        Button2       matlab.ui.control.Button        % Receive
        UIAxes        matlab.ui.control.UIAxes        % 发送信息波形
        UIAxes2       matlab.ui.control.UIAxes        % 接收信息波形
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % TextArea value changed function
        function TextAreaValueChanged(app)
            global fun;
            fun= app.TextArea.Value;
            fun = char(fun);
            % whos fun

            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            global fun;
            global msg;
            
            
            load('highpass.mat');
            load('lowpass.mat');
            load('bandpass.mat');
            
            % Verify input validity
            try
                result = eval(fun);
            catch
                disp('Syntax Error !')
            end
            
            fun_binary_str = dec2bin(fun,7);
            
            % fun_binary_str = str2num( fun_binary_str );
            
            
            fun_binary_str_row = reshape(fun_binary_str',1,length(fun_binary_str(:)));
            
            fun_binary_row = str2num(fun_binary_str_row')';
            
            fun_binary_code=encode(fun_binary_row,7,4,'hamming/binary');
            % decode(fun_binary_code,7,4,'hamming/binary')
            
            msg = zeros( 1,400+length(fun_binary_code)*400);
            
            dt=1.2500e-004;
            t=dt:dt:400*dt;
            SIN1 = sin(2*pi*1000*t);
            SIN2 = sin(2*pi*200*t).*0;
            
            msg(1:400) = 10*SIN1;
            for ii = 2:(length(fun_binary_code)+1)
                if(fun_binary_code(ii-1) == 1)
                    msg(1+400*(ii-1):400*ii) = 10*SIN1;
                else
                    msg(1+400*(ii-1):400*ii) = 10*SIN2;
                end
            end
            
            head=[SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1];
            sound(head,20000);
            disp('呼叫中…')
            pause(0.4+1+0.5);
            
            sound(msg,16000);
            disp('发送中…');
            
            % pause(length(msg(:))/8000);
            pause(5.5);
            disp('发送结束，准备接收!');
            plot(app.UIAxes,msg);
            hold(app.UIAxes,'on')
            judge = zeros(1,length(msg));
            judge(200:400:length(msg)) = 12;
            plot(app.UIAxes,judge);
            hold(app.UIAxes,'off');
            
            voicing_receive = audiorecorder(16000,16,1);
            recordblocking(voicing_receive, 5);
            voice = getaudiodata(voicing_receive);
            
            % bp = fir1(48,[0.16,0.28]);
            % voice= filter(bp,1,voice);
            voice_h = filter(bandpassvector,1,voice);
            voice_l = filter(Lowpassvector,1,voice);
            
            am_h = abs(hilbert(voice_h));% 包络
            am_l = abs(hilbert(voice_l));
            
            % 抽样判决
            voice_1 = find(am_h > 0.3*max(am_h));
            for ii = 1:400
                if (voice_1(ii) + 1 == voice_1(ii+1) & voice_1(ii+1) + 1 == voice_1(ii+2) & ...
                        voice_1(ii+2) + 1 == voice_1(ii+3) & voice_1(ii+3) + 1 == voice_1(ii+4) & ...
                        voice_1(ii+4) + 1 == voice_1(ii+5) & voice_1(ii+5) + 1 == voice_1(ii+6) & ...
                        voice_1(ii+6) + 1 == voice_1(ii+7) & voice_1(ii+7) + 1 == voice_1(ii+8) & ...
                        voice_1(ii+8) + 1 == voice_1(ii+9) & voice_1(ii+40) + 61 == voice_1(ii+101) & ...
                        voice_1(ii) +75 == voice_1(ii+75) ...
                        )
                    msg_begin = voice_1(ii);
                    break;
                end
            end
            
            % msg_receive = double( (am(msg_begin + 400:800:msg_begin + 800*79 +400)>0.2*max(am)) );
            
            sample_t = 200;
            
            msg_receive = double( (am_h(msg_begin + sample_t:400:msg_begin + 400*119 +sample_t) > ...
                0.3*max(am_h)));
                % ( am_l(msg_begin + sample_t:400:msg_begin + 400*119 +sample_t) )) );
            
            msg_receive = msg_receive(2:end);
            
            [msg_receive_decode,err] = decode(msg_receive,7,4,'hamming/binary');
            
            err_sum = sum(err); % 总的误码个数
            
            BER = err_sum/length(msg_receive)
            
            fun_binary_num = zeros(7,30);
            
            msg_receive_decode_row_0 = [msg_receive_decode',zeros(1,119 - length(msg_receive_decode))];
            
            for ii = 1:119
                fun_binary_num(ii) = msg_receive_decode_row_0(ii);
            end
            
            plot(app.UIAxes2,voice_h);
            hold(app.UIAxes2,'on')
            judge = zeros(1,400*80);
            judge(msg_begin+sample_t:400:msg_begin + 400*119 +sample_t) = max(am_h);
            plot(app.UIAxes2,judge);
            hold(app.UIAxes2,'off');
            
            
            fun_binary_num = fun_binary_num';
            
            fun_binary_str = num2str(fun_binary_num,'%d');
            
            fun_dec = bin2dec(fun_binary_str);
            
            result_receive = eval(char(fun_dec)')
            
            % app.TextArea2.Value = char(fun_dec)';
            app.TextArea2.Value = {char(fun_dec)';['BER = ',num2str(BER)]};


%             fun_binary_str_row = reshape(fun_binary_str',1,length(fun_binary_str(:)));
%             
%             msg = zeros( 1,400+length(fun_binary_str_row)*400);
%             
%             dt=1.2500e-004;
%             t=dt:dt:400*dt;
%             SIN1 = sin(2*pi*1000*t);
%             SIN2 = sin(2*pi*200*t).*0;
%             
%             msg(1:400) = 10*SIN1;
%             for ii = 2:(length(fun_binary_str_row)+1)
%                 if(fun_binary_str_row(ii-1) == '1')
%                     msg(1+400*(ii-1):400*ii) = 10*SIN1;
%                 else
%                     msg(1+400*(ii-1):400*ii) = 10*SIN2;
%                 end
%             end
%             
%             head=[SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1,SIN1];
%             sound(head,32000);
%             disp('呼叫中…')
%             pause(0.4+1);
%             
%             sound(msg,10000);
%             disp('发送中…');
%             
%             % pause(length(msg(:))/8000);
%             pause(4.5);
%             disp('发送结束，准备接收!');
%             plot(app.UIAxes,msg);
%             hold(app.UIAxes,'on')
%             judge = zeros(1,length(msg));
%             judge(200:400:length(msg)) = 12;
%             plot(app.UIAxes,judge);
%             hold(app.UIAxes,'off');
%             
%             voicing_receive = audiorecorder(10000,16,1);
%             recordblocking(voicing_receive, 7);
%             voice = getaudiodata(voicing_receive);
%             
%             % bp = fir1(48,[0.16,0.28]);
%             % voice= filter(bp,1,voice);
%             voice = filter(bandpassvector,0.25,voice);
%             
%             am = abs(hilbert(voice));% 包络
%             
%             % 抽样判决
%             voice_1 = find(am > 0.3*max(am));
%             for ii = 1:400
%                 if (voice_1(ii) + 1 == voice_1(ii+1) & voice_1(ii+1) + 1 == voice_1(ii+2))
%                     msg_begin = voice_1(ii);
%                     break;
%                 end
%             end
%             
%             % msg_receive = double( (am(msg_begin + 400:800:msg_begin + 800*79 +400)>0.2*max(am)) );
%             msg_receive = double( (am(msg_begin + 250:400:msg_begin + 400*79 +250)>0.3*max(am)) );
%             
%             fun_binary_num = zeros(7,30);
%             
%             for ii = 2:80
%                 fun_binary_num(ii-1) = msg_receive(ii);
%             end
%             
%             plot(app.UIAxes2,voice);
%             hold(app.UIAxes2,'on')
%             judge = zeros(1,400*80);
%             judge(msg_begin+250:400:msg_begin + 400*79 +250) = max(am);
%             plot(app.UIAxes2,judge);
%             hold(app.UIAxes2,'off');
%             
%             fun_binary_num = fun_binary_num';
%             
%             fun_binary_str = num2str(fun_binary_num,'%d');
%             
%             fun_dec = bin2dec(fun_binary_str);
%             
%             result_receive = eval(char(fun_dec)')
%             
%             app.TextArea2.Value = char(fun_dec)';
            

        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            flag = 0;
            load('lowpass.mat');
            load('highpass.mat');
            load('bandpass.mat');
            
            app.TextArea.Value = '等待中';
            app.TextArea2.Value = '等待中';
            
            while(flag==0)
                
                voicing = audiorecorder(20000,16,1);
                recordblocking(voicing, 0.5);
                voice = getaudiodata(voicing);
                
                voice = filter(bandpassvector,0.18,voice);
                am = abs(hilbert(voice));% 包络

                voice_0 = find(am > 0.15);
                % disp('lab')
                % length(voice_0)
                if (max(abs(voice)) > 0.15 & length(voice_0) > 200)
                    flag=1;
                    pause(0.3);
                    disp('已接通，开始接收信息…')
                    app.TextArea.Value = '已接通，开始接收信息…';
                end
            end
            
            
            voicing = audiorecorder(16000,16,1);
            recordblocking(voicing, 6);
            voice = getaudiodata(voicing);
            
            
            voice = filter(bandpassvector,0.25,voice);
            
            am = abs(hilbert(voice));% 包络
            
            % 抽样判决
            voice_1 = find(am > 0.3*max(am));
            for ii = 1:400
                if (voice_1(ii) + 1 == voice_1(ii+1) & voice_1(ii+1) + 1 == voice_1(ii+2) & ...
                    voice_1(ii+2) + 1 == voice_1(ii+3) & voice_1(ii+3) + 1 == voice_1(ii+4) &...
                    voice_1(ii+4) + 1 == voice_1(ii+5) & voice_1(ii+5) + 1 == voice_1(ii+6) &...
                    voice_1(ii+6) + 1 == voice_1(ii+7) & voice_1(ii+7) + 1 == voice_1(ii+8) &...
                    voice_1(ii+8) + 1 == voice_1(ii+9) & voice_1(ii+9) + 1 == voice_1(ii+10) )
                    msg_begin = voice_1(ii);
                    break;
                end
            end
            
            
            plot(app.UIAxes2,voice);
            hold(app.UIAxes2,'on')
            judge = zeros(1,400*120);
            judge(msg_begin+250:400:msg_begin + 400*119 +250) = max(am);
            plot(app.UIAxes2,judge);
            hold(app.UIAxes2,'off');
            
            msg = double( (am(msg_begin + 250:400:msg_begin + 400*119 +250)>0.3*max(am)) );
            
            msg = msg(2:end);
            
            [msg_decode,err] = decode(msg,7,4,'hamming/binary');
            
            err_sum = sum(err); % 总的误码个数
            
            BER = err_sum/length(msg)
            
            fun_binary_num = zeros(7,30);
            
            msg_decode_row_0 = [msg_decode',zeros(1,119 - length(msg_decode))];
            
            for ii = 1:119
                fun_binary_num(ii) = msg_decode_row_0(ii);
            end
            
            
            fun_binary_num = fun_binary_num';
            
            fun_binary_str = num2str(fun_binary_num,'%d');
            
            
            fun_dec = bin2dec(fun_binary_str);
            
            disp(char(fun_dec)')
            
            app.TextArea.Value = char(fun_dec)';
            
            result = eval(char(fun_dec)')
            
            result_str = num2str(result);
            
            % app.TextArea2.Value = result_str;
            
            app.TextArea2.Value = {result_str;['BER = ',num2str(BER)]};
            
            result_binary_str = dec2bin(result_str,7);
            
            result_binary_str_row = reshape(result_binary_str',1,length(result_binary_str(:)));
            
            result_binary_row = str2num(result_binary_str_row')';
            
            result_binary_code=encode(result_binary_row,7,4,'hamming/binary');
            
            msg = zeros( 1,400+length(result_binary_code)*400);
            
            dt=1.2500e-004;
            t=dt:dt:400*dt;
            SIN1 = sin(2*pi*1000*t);
            SIN2 = sin(2*pi*200*t);
            
            msg(1:400) = 10*SIN1;
            for ii = 2:(length(result_binary_code)+1)
                if(result_binary_code(ii-1) == 1)
                    msg(1+400*(ii-1):400*ii) = 10*SIN1;
                else
                    msg(1+400*(ii-1):400*ii) =  5*SIN2;
                end
            end
            
            
            % 开始回发结果
            pause(1);
            sound(msg,16000);
            
            plot(app.UIAxes,msg);
            hold(app.UIAxes,'on')
            judge = zeros(1,length(msg));
            judge(200:400:length(msg)) = 12;
            plot(app.UIAxes,judge);
            hold(app.UIAxes,'off');


%             while(flag==0)
%                 
%                 voicing = audiorecorder(32000,16,1);
%                 recordblocking(voicing, 0.5);
%                 voice = getaudiodata(voicing);
%                 
%                 voice = filter(bandpassvector,0.18,voice);
%                 if max(abs(voice)) > 0.2
%                     flag=1;
%                     pause(0.6);
%                     disp('已接通，开始接收信息…')
%                     app.TextArea.Value = '已接通，开始接收信息…';
%                 end
%             end
%             
%             
%             voicing = audiorecorder(10000,16,1);
%             recordblocking(voicing, 6);
%             voice = getaudiodata(voicing);
%             
%             % voice = abs(voice);
%             % for ii = 1:9000*5
%             %     if voice(ii) > 0.6
%             %         voice(ii) = 0.6;
%             %     end
%             % end
%             
%             % bp = fir1(48,[0.16,0.28]);
%             % voice= filter(bp,1,voice);
%             
%             voice = filter(bandpassvector,0.25,voice);
%             
%             am = abs(hilbert(voice));% 包络
%             
%             % 抽样判决
%             voice_1 = find(am > 0.2*max(am));
%             for ii = 1:400
%                 if (voice_1(ii) + 1 == voice_1(ii+1) & voice_1(ii+1) + 1 == voice_1(ii+2))
%                     msg_begin = voice_1(ii);
%                     break;
%                 end
%             end
%             
%             plot(app.UIAxes2,voice);
%             hold(app.UIAxes2,'on')
%             judge = zeros(1,400*120);
%             judge(msg_begin+250:400:msg_begin + 400*119 +250) = max(am);
%             plot(app.UIAxes2,judge);
%             hold(app.UIAxes2,'off');
%             
%             
%             msg = double( (am(msg_begin + 250:400:msg_begin + 400*109 +250)>0.2*max(am)) );
%             
%             fun_binary_num = zeros(7,30);
%             
%             for ii = 2:110
%                 fun_binary_num(ii-1) = msg(ii);
%             end
%             
%             fun_binary_num = fun_binary_num';
%             
%             fun_binary_str = num2str(fun_binary_num,'%d');
%             
%             fun_dec = bin2dec(fun_binary_str);
%             
%             disp(char(fun_dec)')
%             
%             app.TextArea.Value = char(fun_dec)';
%             
%             result = eval(char(fun_dec)')
%             
%             result_str = num2str(result);
%             
%             app.TextArea2.Value = result_str;
%             
%             result_binary_str = dec2bin(result_str,7);
%             
%             result_binary_str_row = reshape(result_binary_str',1,length(result_binary_str(:)));
%             
%             msg = zeros( 1,400+length(result_binary_str_row)*400);
%             
%             dt=1.2500e-004;
%             t=dt:dt:400*dt;
%             SIN1 = sin(2*pi*1000*t);
%             SIN2 = sin(2*pi*200*t);
%             
%             msg(1:400) = 10*SIN1;
%             for ii = 2:(length(result_binary_str_row)+1)
%                 if(result_binary_str_row(ii-1) == '1')
%                     msg(1+400*(ii-1):400*ii) = 10*SIN1;
%                 else
%                     msg(1+400*(ii-1):400*ii) = 5*SIN2;
%                 end
%             end
%             
%             % 开始回发结果
%             pause(1);
%             % sound(msg,16000);
%             sound(msg,10000);
%             
%             plot(app.UIAxes,msg);
%             hold(app.UIAxes,'on')
%             judge = zeros(1,length(msg));
%             judge(200:400:length(msg)) = 12;
%             plot(app.UIAxes,judge);
%             hold(app.UIAxes,'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '功能';
            app.ButtonGroup.Units = 'pixels';
            app.ToggleButton = uitogglebutton(app.ButtonGroup);
            app.ToggleButton.Text = '发送端';
            app.ToggleButton.Value = true;
            app.ToggleButton2 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton2.Text = '接收端';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'formula';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'result';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Send';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Receive';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '发送信息波形');
            xlabel(app.UIAxes, 't');
            ylabel(app.UIAxes, 'msg');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '接收信息波形');
            xlabel(app.UIAxes2, 't');
            ylabel(app.UIAxes2, 'voice');
        end
    end

    methods (Access = public)

        % Construct app
        function app = JMC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end